classdef cam_design < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CamDesignerUIFigure           matlab.ui.Figure
        INPUTDATAPanel                matlab.ui.container.Panel
        InfoTable                     matlab.ui.control.Table
        INSERTButton                  matlab.ui.control.Button
        CACULATEButton                matlab.ui.control.Button
        UNDOButton                    matlab.ui.control.Button
        DisplacementsfromLabel        matlab.ui.control.Label
        beta_last                     matlab.ui.control.EditField
        s_first                       matlab.ui.control.EditField
        s_last                        matlab.ui.control.EditField
        MotionCurveDropDownLabel      matlab.ui.control.Label
        MotionCurveDropDown           matlab.ui.control.DropDown
        mmL9                          matlab.ui.control.Label
        beta_first                    matlab.ui.control.EditField
        PlanarCamFollowerTypeLabel    matlab.ui.control.Label
        CamTypeDropDown               matlab.ui.control.DropDown
        UIAxes_xs                     matlab.ui.control.UIAxes
        info_motion                   matlab.ui.control.Button
        toLabel                       matlab.ui.control.Label
        toLabel_2                     matlab.ui.control.Label
        TheInformationofFollowerMotionLabel  matlab.ui.control.Label
        CamshaftAnglexfromLabel       matlab.ui.control.Label
        UnitLabel                     matlab.ui.control.Label
        UnitGroup                     matlab.ui.container.ButtonGroup
        DegreeButton                  matlab.ui.control.RadioButton
        RadianButton                  matlab.ui.control.RadioButton
        Panel                         matlab.ui.container.Panel
        LockCheckBox                  matlab.ui.control.CheckBox
        CamshaftAngularVelocityLabel  matlab.ui.control.Label
        Offset1Input                  matlab.ui.control.NumericEditField
        RollerRadInput                matlab.ui.control.NumericEditField
        Offset2Input                  matlab.ui.control.NumericEditField
        RangeofOffsetLabel            matlab.ui.control.Label
        OmegaInput                    matlab.ui.control.NumericEditField
        PivotLabel                    matlab.ui.control.Label
        RollerRadLabel                matlab.ui.control.Label
        rpmLabel                      matlab.ui.control.Label
        mmL                           matlab.ui.control.Label
        R_bs                          matlab.ui.control.NumericEditField
        R_bsLabel                     matlab.ui.control.Label
        mmL_2                         matlab.ui.control.Label
        mmL_3                         matlab.ui.control.Label
        ResolutionperSectionLabel     matlab.ui.control.Label
        ResolutionInput               matlab.ui.control.NumericEditField
        OUTPUTDATAPanel               matlab.ui.container.Panel
        EXPORTButton                  matlab.ui.control.Button
        mmLabel                       matlab.ui.control.Label
        mmLabel_2                     matlab.ui.control.Label
        mmLabel_4                     matlab.ui.control.Label
        mmLabel_5                     matlab.ui.control.Label
        OffDistLabel                  matlab.ui.control.Label
        OffDistOutput                 matlab.ui.control.NumericEditField
        BaseRLabel                    matlab.ui.control.Label
        BaseROutput                   matlab.ui.control.NumericEditField
        DistPrivotOutput              matlab.ui.control.Label
        DistPiOutput                  matlab.ui.control.NumericEditField
        FolArmLenLabel                matlab.ui.control.Label
        FolArmLenOutput               matlab.ui.control.NumericEditField
        UIAxes_CamPro                 matlab.ui.control.UIAxes
        info_camsize                  matlab.ui.control.Button
        Author                        matlab.ui.control.Label
    end


    properties (Access = private)
        camtype
        t_cell = cell(0,5) % motion curve
        Rf % roller radius
        xs
        ys
        phi = []
        rho
        s_bspl
        v_bspl
        a_bspl
        j_bspl
        n_bspl
        bspl_app = []% Description
        x = []
        s = []
        v = []
        a = []
        j = []
        motion_app = []
        motion_check
        cam_app = []
        cam_check
    end

    methods (Access = public)
    
        function tranferdata(app,table,n)
            if app.DegreeButton.Value
                omega = app.OmegaInput.Value*360/60;
            else
                omega = app.OmegaInput.Value*2*pi/60;
            end
            app.s_bspl = [table(:,1) table(:,2)];
            app.v_bspl = [table(:,1) table(:,3)]./[1 omega];
            app.a_bspl = [table(:,1) table(:,4)]./[1 omega^2];
            app.j_bspl = [table(:,1) table(:,5)]./[1 omega^3];
            k = []; l = []; m = [];
            for i=1:size(table,1)
                if isnan(table(i,3)), k=[k i]; end %#ok<AGROW>
                if isnan(table(i,4)), l=[l i]; end %#ok<AGROW>
                if isnan(table(i,5)), m=[m i]; end %#ok<AGROW>
            end
            app.v_bspl(k,:)=[];
            app.a_bspl(l,:)=[];
            app.j_bspl(m,:)=[];
            app.beta_last.Value = sprintf('%g',app.s_bspl(size(app.s_bspl,1),1));
            app.s_last.Value = sprintf('%g',app.s_bspl(size(app.s_bspl,1),2));
            app.n_bspl = n;
            app.INSERTButton.Enable='on';
        end
        
        function change2dwell(app)
            app.MotionCurveDropDown.Value = 'Dwell';
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: CamTypeDropDown
        function CamTypeDropDownValueChanged(app, event)
            % app.PivotLabel.Visible='off';
            app.CamDesignerUIFigure.Position = [0,0,602,753];
            app.camtype = app.CamTypeDropDown.Value;
            switch app.CamTypeDropDown.Value
                case 'Cam with Translating Roller Follower'
                    dummy_1 = 1;
                    dummy_3 = 0;
                    dummy_4 = 0;
                    app.RangeofOffsetLabel.Position = [10,40,167,18];
                    app.RangeofOffsetLabel.Text = 'Range of Offset';
                    app.RollerRadLabel.Text = 'Roller Radius';
                    app.PivotLabel.Visible = 0;
                    app.Offset1Input.Visible = 1;
                    app.mmL9.Text = 'mm';
                    app.mmLabel_2.Text = 'mm';
                    ylabel(app.UIAxes_xs, 's (mm)');
                case 'Cam with Ocsilating Roller Follower'
                    dummy_1 = 1;
                    dummy_3 = 1;
                    dummy_4 = 1;
                    app.RangeofOffsetLabel.Position = [10,46,167,18];
                    app.RangeofOffsetLabel.Text = 'Distance between';
                    app.FolArmLenLabel.Text = 'Follower Arm Length';
                    app.RollerRadLabel.Text = 'Roller Radius';
                    app.PivotLabel.Visible = 1;
                    app.Offset1Input.Visible = 0;
                    if app.DegreeButton.Value
                        app.mmL9.Text = 'deg';
                        ylabel(app.UIAxes_xs, 's (deg)');
                        app.mmLabel_2.Text = 'deg';
                    else
                        app.mmL9.Text = 'rad';
                        ylabel(app.UIAxes_xs, 's (rad)');
                        app.mmLabel_2.Text = 'rad';
                    end
                case 'Cam with Translating Flat-faced Follower'
                    dummy_1 = 0;
                    dummy_3 = 1;
                    dummy_4 = 0;
                    app.DistPrivotOutput.Text = 'Min. Face Width';
                    app.PivotLabel.Visible = 0;
                    app.Offset1Input.Visible = 0;
                    app.mmL9.Text = 'mm';
                    app.mmLabel_2.Text = 'mm';
                    ylabel(app.UIAxes_xs, 's (mm)');
                case 'Cam with Ocsilating Flat-faced Follower'
                    dummy_1 = 1;
                    dummy_3 = 1;
                    dummy_4 = 1;
                    app.RangeofOffsetLabel.Position = [10,46,167,18];
                    app.RangeofOffsetLabel.Text = 'Initial distance between';
                    app.RollerRadLabel.Text = 'Offset of Follower';
                    app.FolArmLenLabel.Text = 'Min. Face Width';
                    app.OffDistLabel.Text = 'Angle Initial';
                    app.PivotLabel.Visible = 1;
                    app.Offset1Input.Visible = 0;
                    if app.DegreeButton.Value
                        app.mmL9.Text='deg';
                        ylabel(app.UIAxes_xs, 's (deg)');
                        app.mmLabel_2.Text = 'deg';
                    else
                        app.mmL9.Text='rad';
                        ylabel(app.UIAxes_xs, 's (rad)');
                        app.mmLabel_2.Text = 'rad';
                    end
            end
            app.OffDistLabel.Visible = dummy_1;
            app.OffDistOutput.Visible = dummy_1;
            app.mmLabel_2.Visible = dummy_1;
            app.RangeofOffsetLabel.Visible = dummy_1;
            app.Offset2Input.Visible = dummy_1;
            app.mmL.Visible = dummy_1;
            app.RollerRadInput.Visible = dummy_1;
            app.RollerRadLabel.Visible = dummy_1;
            app.mmL_2.Visible = dummy_1;
            app.DistPrivotOutput.Visible = dummy_3;
            app.DistPiOutput.Visible = dummy_3;
            app.mmLabel_4.Visible = dummy_3;
            app.FolArmLenLabel.Visible = dummy_4;
            app.FolArmLenOutput.Visible = dummy_4;
            app.mmLabel_5.Visible = dummy_4;
            
        end

        % Button pushed function: INSERTButton
        function INSERTButtonPushed(app, event)
            if double(str2sym(app.beta_first.Value))<double(str2sym(app.beta_last.Value)) &&...
                    ~isempty(double(str2sym(app.beta_last.Value))) &&...
                    ~isempty(double(str2sym(app.s_last.Value))) 
                % Xoa do thi
                if app.motion_check
                    app.motion_check = 0;
                    delete(app.motion_app)
                end
                % Chuyen cac gai tri bf bl sf sl vao bang thong tin
                sf = double(str2sym(app.s_first.Value));
                if strcmp(app.MotionCurveDropDown.Value,'Dwell'), sl = sf;
                else, sl = double(str2sym(app.s_last.Value)); end
                bf = double(str2sym(app.beta_first.Value));
                bl = double(str2sym(app.beta_last.Value));
                app.t_cell = [app.t_cell; table2cell(array2table([bf bl sf sl]))...
                    app.MotionCurveDropDown.Value];
                app.InfoTable.Data=app.t_cell;
                % Khoi tao gia tri beta
                x_d = bf : (bl-bf)/app.ResolutionInput.Value : bl; 
                switch app.MotionCurveDropDown.Value
                    % s_d, v_d, a_d, j_d: _d means dummy
                    case 'Cycloidal'
                        [s_d, v_d, a_d, j_d] = cycloidal(x_d,sf,sl);
                    case 'Modified Trapezoidal'
                        [s_d, v_d, a_d, j_d] = trap_mod(x_d,sf,sl);
                    case 'Modified Sinusoidal'
                        [s_d, v_d, a_d, j_d] = sinu_mod(x_d,sf,sl);
                    case 'Uniform Velocity' %chinh lai sau
                        [s_d, v_d, a_d, j_d] = v_const(x_d,sf,sl);
                    case '3-4-5 Polynomial'
                        [s_d, v_d, a_d, j_d] = poly345(x_d,sf,sl);
                    case '4-5-6-7 Polynomial'
                        [s_d, v_d, a_d, j_d] = poly4567(x_d,sf,sl);
                    case 'B-Spline'
                        [s_d, v_d, a_d, j_d] = bspl_optimize(x_d,app.s_bspl,...
                            app.v_bspl,app.a_bspl,app.j_bspl,app.n_bspl);
                        change2dwell(app)
                    case 'Dwell'
                        [s_d, v_d, a_d, j_d] = dwell(x_d,sf);
                end
                % Loai ca diem trung
                if ~isempty(app.x)
                    x_d(1) = []; s_d(1) = []; v_d(1) = []; a_d(1) = []; j_d(1) = [];
                else
                    app.DegreeButton.Enable = 'off';
                    app.RadianButton.Enable = 'off';
                    app.CamTypeDropDown.Enable = 'on';
                end
                % Luu vao cac property
                app.x = [app.x, x_d];
                app.s = [app.s, s_d];
                app.v = [app.v, v_d];
                app.a = [app.a, a_d];
                app.j = [app.j, j_d];
                % Xuat do thi
                plot(app.UIAxes_xs,app.x,app.s,'b');
                % Mo cac nut lai va hien nut info
                app.info_motion.Enable = 'On';
                if any(bl == [360 2*pi])
                    app.CACULATEButton.Enable='on';
                    app.INSERTButton.Enable = 'off';
                end
                app.UNDOButton.Enable='on';
                % Update cac gia tri bf sf
                app.beta_first.Value=app.beta_last.Value;
                app.beta_last.Value = '';
                app.beta_first.Enable='off';
                app.s_first.Value=app.s_last.Value;
                if strcmp(app.MotionCurveDropDown.Value,'Dwell')==0,app.s_last.Value = ''; end
                app.s_first.Enable='off';
            end
        end

        % Button pushed function: CACULATEButton
        function CACULATEButtonPushed(app, event)
            % Doi ve rad het
            omega = app.OmegaInput.Value*2*pi/60;
            if strcmp(app.mmL9.Text, 'mm')
                if app.DegreeButton.Value
                    X = app.x*pi/180;
                    S = app.s;
                    V = app.v*180/pi;
                    A = app.a*(180/pi)^2;
                else
                    X = app.x; S = app.s; V = app.v; A = app.a;
                end
            elseif strcmp(app.mmL9.Text, 'deg')
                X = app.x*pi/180;
                S = app.s*pi/180;
                V = app.v;
                A = app.a*(180/pi);
            else
                X = app.x; S = app.s; V = app.v; A = app.a;
            end
            % Tinh cam sizes
            switch app.CamTypeDropDown.Value
                case 'Cam with Translating Roller Follower'
                    app.OffDistLabel.Text = 'Offset Distance';
                    [app.BaseROutput.Value,app.OffDistOutput.Value,app.phi,app.rho,app.xs,app.ys,~,~] = ...
                        trans_roller(X,S,V,A,omega,app.RollerRadInput.Value,...
                        app.Offset1Input.Value,app.Offset2Input.Value,app.R_bs.Value);
                case 'Cam with Ocsilating Roller Follower'
                    app.OffDistLabel.Text = 'Angle Initial';
                    app.DistPrivotOutput.Text = 'Dist Pivot Cam-Fol';
                    [app.BaseROutput.Value, beta, app.FolArmLenOutput.Value,...
                        app.phi, app.rho, app.xs, app.ys, ~, ~] = ...
                        ocs_roller(X,S,V,A,omega,app.RollerRadInput.Value,app.Offset2Input.Value);
                    app.DistPiOutput.Value = app.Offset2Input.Value;
                    if app.DegreeButton.Value
                        app.OffDistOutput.Value = beta/pi*180;
                    end
                case 'Cam with Translating Flat-faced Follower'
                    app.DistPiOutput.Value = max(V)-min(V);
                    [app.BaseROutput.Value,app.rho,app.xs,app.ys,~,~] = ...
                        trans_flat(X,S,V,A,omega,app.R_bs.Value);
                case 'Cam with Ocsilating Flat-faced Follower'
                    [app.BaseROutput.Value, l, beta, c, app.rho, app.xs, app.ys,~,~] = ...
                        ocs_flat(X,S,V,A,omega,app.Offset2Input.Value,app.RollerRadInput.Value);
                    app.DistPiOutput.Value = c;
                    app.FolArmLenOutput.Value = l;
                    if app.DegreeButton.Value
                        app.OffDistOutput.Value = beta/pi*180;
                    end
            end
            % Xuat bien dang cam
            axis(app.UIAxes_CamPro,'equal');
            plot(app.UIAxes_CamPro,app.xs,app.ys,'r');
            line(app.UIAxes_CamPro,[min(app.xs)*1.1 max(app.xs)*1.1],[0 0],'linestyle','--');
            line(app.UIAxes_CamPro,[0 0],[min(app.ys)*1.1 max(app.ys)*1.1],'linestyle','--');
            if app.cam_check
                app.cam_check = 0;
                delete(app.cam_app)
            end
            app.CamDesignerUIFigure.Position = [0,0,1023,753];
        end

        % Button pushed function: EXPORTButton
        function EXPORTButtonPushed(app, event)
            %Xuat file
            app.EXPORTButton.BackgroundColor = [1 0 0];
            [file, path] = uiputfile({'*.txt', 'Plain Text (*.txt)';...
                '*.csv', 'CSV (Comma delimited) (*.csv)';...
                '*.pts', '3D Points File (*.pts)';...
                '*.*', 'All Files (*.*)'});
            if file
                file_cam = sprintf('%s%s',path,file);
                fileID = fopen(file_cam,'w');
                if strcmp(file_cam(end-3:end),'.csv')
                    fprintf(fileID,'%.4f, %.4f\n',[app.xs; app.ys]);
                elseif strcmp(file_cam(end-3:end),'.txt')
                    fprintf(fileID,'%.4f %.4f\n',[app.xs; app.ys]);
                else
                    fprintf(fileID,'%.4f %.4f %.4f\n',[app.xs; app.ys; app.xs*0]);
                end 
                fclose(fileID);
            end
            app.EXPORTButton.BackgroundColor = [0 1 0];
        end

        % Button pushed function: UNDOButton
        function UNDOButtonPushed(app, event)
            % Xoa du lieu bang thong tin
            app.t_cell(end,:)=[];
            app.InfoTable.Data=app.t_cell;
            % Bat tat ca nut chuc nang va thay doi bf, sf
            if isempty(app.t_cell)
                app.CACULATEButton.Enable='off';
                app.UNDOButton.Enable='off';
                app.beta_last.Value = '';
                app.beta_first.Value = '0';
                app.beta_first.Enable='on';
                app.s_last.Value = '';
                app.s_first.Value = '0';
                app.s_first.Enable='on';
            else
                app.beta_first.Value=sprintf('%d',app.t_cell{end,2});
                app.beta_last.Value = '';
                app.beta_first.Enable='off';
                app.s_first.Value=sprintf('%d',app.t_cell{end,4});
                if strcmp(app.MotionCurveDropDown.Value,'Dwell')==0,app.s_last.Value = '';end
                app.s_first.Enable='off';
            end
            if strcmp(app.MotionCurveDropDown.Value,'Dwell'), app.s_last.Value = app.s_first.Value; end
            % Xoa du lieu do thi
            app.x = app.x(1:end-app.ResolutionInput.Value);
            app.s = app.s(1:end-app.ResolutionInput.Value);
            app.v = app.v(1:end-app.ResolutionInput.Value);
            app.a = app.a(1:end-app.ResolutionInput.Value);
            app.j = app.j(1:end-app.ResolutionInput.Value);
            % Xuat do thi va hien nut INSERT
            plot(app.UIAxes_xs,app.x,app.s,'b');
            app.INSERTButton.Enable = 'on';
            % Update do thi
            if app.motion_check
                app.motion_check = 0;
                delete(app.motion_app);
            end
            % Xoa gia tri du cuoi cung
            if length(app.x)==1 
                app.x = []; app.s = []; app.v = []; app.a = []; app.j = []; 
                app.DegreeButton.Enable = 'on';
                app.RadianButton.Enable = 'on';
                app.CamTypeDropDown.Enable = 'on';
                app.info_motion.Enable = 'Off';
                app.INSERTButton.Enable = 'Off';
            end
        end

        % Value changed function: MotionCurveDropDown
        function MotionCurveDropDownValueChanged(app, event)
            if strcmp(app.MotionCurveDropDown.Value,'Dwell')
                app.s_last.Value=app.s_first.Value;
                app.s_last.Enable='off';
            elseif strcmp(app.MotionCurveDropDown.Value,'B-Spline')
                app.bspl_app = app_bspl(app,double(str2sym(app.beta_first.Value)),double(str2sym(app.s_first.Value)));
            else
                app.s_last.Enable='on';
            end
        end

        % Value changing function: s_last
        function s_lastValueChanging(app, event)
            changingValue = event.Value;
            if isempty(changingValue)
                app.INSERTButton.Enable='off';
            else
                app.INSERTButton.Enable='on';
            end
        end

        % Button pushed function: info_motion
        function info_motionPushed(app, event)
            app.motion_check = 1;
            app.motion_app = app_motion(app.x,app.s,app.v,app.a,app.j, app.OmegaInput.Value, app.DegreeButton.Value, app.CamTypeDropDown.Value);
        end

        % Value changed function: LockCheckBox
        function LockCheckBoxValueChanged(app, event)
            if app.LockCheckBox.Value, value = 'off';
            else, value = 'on'; end
            app.OmegaInput.Enable = value;
            app.Offset2Input.Enable = value;
            app.Offset1Input.Enable = value;
            app.RollerRadInput.Enable = value;
            app.R_bs.Enable = value;
        end

        % Selection changed function: UnitGroup
        function UnitGroupSelectionChanged(app, event)
            if app.DegreeButton.Value
                xlabel(app.UIAxes_xs, 'x (deg)');
                if strcmp(app.CamTypeDropDown.Value,'Cam with Ocsilating Roller Follower') ||...
                       strcmp(app.CamTypeDropDown.Value,'Cam with Ocsilating Flat-faced Follower')
                   app.mmL9.Text='deg';
                   ylabel(app.UIAxes_xs, 's (deg)');
                end
            else
                xlabel(app.UIAxes_xs, 'x (rad)');
                if strcmp(app.CamTypeDropDown.Value,'Cam with Ocsilating Roller Follower') ||...
                       strcmp(app.CamTypeDropDown.Value,'Cam with Ocsilating Flat-faced Follower')
                    app.mmL9.Text='rad';
                    ylabel(app.UIAxes_xs, 's (rad)');
                end
            end
            
        end

        % Button pushed function: info_camsize
        function info_camsizePushed(app, event)
            app.cam_check = 1;
            app.cam_app = app_camsize(app.x,app.phi,app.rho,app.CamTypeDropDown.Value,app.DegreeButton.Value);
        end

        % Close request function: CamDesignerUIFigure
        function CamDesignerUIFigureCloseRequest(app, event)
            delete(app.cam_app)
            delete(app.bspl_app)
            delete(app.motion_app)
            delete(app)
        end

        % Value changing function: beta_last
        function beta_lastValueChanging(app, event)
            changingValue = event.Value;
            if isempty(changingValue)
                app.INSERTButton.Enable='off';
            else
                app.INSERTButton.Enable='on';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CamDesignerUIFigure = uifigure('Visible', 'off');
            app.CamDesignerUIFigure.Name = 'Cam Designer';
            app.CamDesignerUIFigure.CloseRequestFcn = createCallbackFcn(app, @CamDesignerUIFigureCloseRequest, true);
            app.INPUTDATAPanel = uipanel(app.CamDesignerUIFigure);
            app.INPUTDATAPanel.Title = '   INPUT DATA';
            app.InfoTable = uitable(app.INPUTDATAPanel);
            app.InfoTable.ColumnName = {'Beta 1'; 'Beta 2'; 'Disp. 1'; 'Disp. 2'; 'Motion Curve'};
            app.InfoTable.RowName = {};
            app.INSERTButton = uibutton(app.INPUTDATAPanel, 'push');
            app.INSERTButton.ButtonPushedFcn = createCallbackFcn(app, @INSERTButtonPushed, true);
            app.INSERTButton.Text = 'INSERT';
            app.CACULATEButton = uibutton(app.INPUTDATAPanel, 'push');
            app.CACULATEButton.ButtonPushedFcn = createCallbackFcn(app, @CACULATEButtonPushed, true);
            app.CACULATEButton.Enable = 'off';
            app.CACULATEButton.Text = 'CACULATE';
            app.UNDOButton = uibutton(app.INPUTDATAPanel, 'push');
            app.UNDOButton.ButtonPushedFcn = createCallbackFcn(app, @UNDOButtonPushed, true);
            app.UNDOButton.Enable = 'off';
            app.UNDOButton.Text = 'UNDO';
            app.DisplacementsfromLabel = uilabel(app.INPUTDATAPanel);
            app.DisplacementsfromLabel.Text = 'Displacement, s from';
            app.beta_last = uieditfield(app.INPUTDATAPanel, 'text');
            app.beta_last.ValueChangingFcn = createCallbackFcn(app, @beta_lastValueChanging, true);
            app.beta_last.Value = '0';
            app.s_first = uieditfield(app.INPUTDATAPanel, 'text');
            app.s_first.Value = '0';
            app.s_last = uieditfield(app.INPUTDATAPanel, 'text');
            app.s_last.ValueChangingFcn = createCallbackFcn(app, @s_lastValueChanging, true);
            app.s_last.Value = '2';
            app.MotionCurveDropDownLabel = uilabel(app.INPUTDATAPanel);
            app.MotionCurveDropDownLabel.Text = 'Motion Curve';
            app.MotionCurveDropDown = uidropdown(app.INPUTDATAPanel);
            app.MotionCurveDropDown.Items = {'Dwell', 'Cycloidal', 'Uniform Velocity', 'Modified Sinusoidal', 'Modified Trapezoidal', '3-4-5 Polynomial', '4-5-6-7 Polynomial', 'B-Spline'};
            app.MotionCurveDropDown.ValueChangedFcn = createCallbackFcn(app, @MotionCurveDropDownValueChanged, true);
            app.MotionCurveDropDown.Value = 'Cycloidal';
            app.mmL9 = uilabel(app.INPUTDATAPanel);
            app.mmL9.Text = 'mm';
            app.beta_first = uieditfield(app.INPUTDATAPanel, 'text');
            app.beta_first.Value = '0';
            app.PlanarCamFollowerTypeLabel = uilabel(app.INPUTDATAPanel);
            app.PlanarCamFollowerTypeLabel.Text = 'Planar Cam - Follower Type';
            app.CamTypeDropDown = uidropdown(app.INPUTDATAPanel);
            app.CamTypeDropDown.Items = {'Cam with Translating Roller Follower', 'Cam with Translating Flat-faced Follower', 'Cam with Ocsilating Roller Follower', 'Cam with Ocsilating Flat-faced Follower'};
            app.CamTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @CamTypeDropDownValueChanged, true);
            app.CamTypeDropDown.Value = 'Cam with Translating Roller Follower';
            app.UIAxes_xs = uiaxes(app.INPUTDATAPanel);
            title(app.UIAxes_xs, 'Displacement')
            xlabel(app.UIAxes_xs, 'x (deg)')
            ylabel(app.UIAxes_xs, 's (mm)')
            app.info_motion = uibutton(app.INPUTDATAPanel, 'push');
            app.info_motion.ButtonPushedFcn = createCallbackFcn(app, @info_motionPushed, true);
            app.info_motion.Enable = 'off';
            app.info_motion.Text = 'i';
            app.toLabel = uilabel(app.INPUTDATAPanel);
            app.toLabel.Text = ' to';
            app.toLabel_2 = uilabel(app.INPUTDATAPanel);
            app.toLabel_2.Text = ' to';
            app.TheInformationofFollowerMotionLabel = uilabel(app.INPUTDATAPanel);
            app.TheInformationofFollowerMotionLabel.Text = 'The Information of Follower Motion';
            app.CamshaftAnglexfromLabel = uilabel(app.INPUTDATAPanel);
            app.CamshaftAnglexfromLabel.Text = 'Camshaft Angle, x from';
            app.UnitLabel = uilabel(app.INPUTDATAPanel);
            app.UnitLabel.Text = 'Unit';
            app.UnitGroup = uibuttongroup(app.INPUTDATAPanel);
            app.UnitGroup.SelectionChangedFcn = createCallbackFcn(app, @UnitGroupSelectionChanged, true);
            app.DegreeButton = uiradiobutton(app.UnitGroup);
            app.DegreeButton.Text = 'Degree';
            app.DegreeButton.Value = true;
            app.RadianButton = uiradiobutton(app.UnitGroup);
            app.RadianButton.Text = 'Radian';
            app.Panel = uipanel(app.INPUTDATAPanel);
            app.LockCheckBox = uicheckbox(app.Panel);
            app.LockCheckBox.ValueChangedFcn = createCallbackFcn(app, @LockCheckBoxValueChanged, true);
            app.LockCheckBox.Text = 'Lock';
            app.CamshaftAngularVelocityLabel = uilabel(app.Panel);
            app.CamshaftAngularVelocityLabel.Text = 'Camshaft Angular Velocity';
            app.Offset1Input = uieditfield(app.Panel, 'numeric');
            app.Offset1Input.Value = -1;
            app.RollerRadInput = uieditfield(app.Panel, 'numeric');
            app.RollerRadInput.Value = 1;
            app.Offset2Input = uieditfield(app.Panel, 'numeric');
            app.Offset2Input.Value = 1;
            app.RangeofOffsetLabel = uilabel(app.Panel);
            app.RangeofOffsetLabel.Text = 'Range of Offset';
            app.OmegaInput = uieditfield(app.Panel, 'numeric');
            app.OmegaInput.Value = 10;
            app.PivotLabel = uilabel(app.Panel);
            app.PivotLabel.Visible = 'off';
            app.PivotLabel.Text = 'Cam''s Pivot vs Follower''s Pivot ';
            app.RollerRadLabel = uilabel(app.Panel);
            app.RollerRadLabel.Text = 'Roller Radius';
            app.rpmLabel = uilabel(app.Panel);
            app.rpmLabel.Text = 'rpm';
            app.mmL = uilabel(app.Panel);
            app.mmL.Text = 'mm';
            app.R_bs = uieditfield(app.Panel, 'numeric');
            app.R_bs.Value = 1;
            app.R_bsLabel = uilabel(app.Panel);
            app.R_bsLabel.Text = 'Start Base Radius';
            app.mmL_2 = uilabel(app.Panel);
            app.mmL_2.Text = 'mm';
            app.mmL_3 = uilabel(app.Panel);
            app.mmL_3.Text = 'mm';
            app.ResolutionperSectionLabel = uilabel(app.INPUTDATAPanel);
            app.ResolutionperSectionLabel.Text = 'Resolution per Section';
            app.ResolutionInput = uieditfield(app.INPUTDATAPanel, 'numeric');
            app.ResolutionInput.Limits = [0 Inf];
            app.ResolutionInput.Value = 100;
            app.OUTPUTDATAPanel = uipanel(app.CamDesignerUIFigure);
            app.OUTPUTDATAPanel.Title = '   OUTPUT DATA';
            app.EXPORTButton = uibutton(app.OUTPUTDATAPanel, 'push');
            app.EXPORTButton.ButtonPushedFcn = createCallbackFcn(app, @EXPORTButtonPushed, true);
            app.EXPORTButton.Text = 'EXPORT';
            app.mmLabel = uilabel(app.OUTPUTDATAPanel);
            app.mmLabel.Text = 'mm';
            app.mmLabel_2 = uilabel(app.OUTPUTDATAPanel);
            app.mmLabel_2.Text = 'mm';
            app.mmLabel_4 = uilabel(app.OUTPUTDATAPanel);
            app.mmLabel_4.Visible = 'off';
            app.mmLabel_4.Text = 'mm';
            app.mmLabel_5 = uilabel(app.OUTPUTDATAPanel);
            app.mmLabel_5.Visible = 'off';
            app.mmLabel_5.Text = 'mm';
            app.OffDistLabel = uilabel(app.OUTPUTDATAPanel);
            app.OffDistLabel.Text = 'Offset Distance';
            app.OffDistOutput = uieditfield(app.OUTPUTDATAPanel, 'numeric');
            app.OffDistOutput.Editable = 'off';
            app.BaseRLabel = uilabel(app.OUTPUTDATAPanel);
            app.BaseRLabel.Text = 'Base Radius';
            app.BaseROutput = uieditfield(app.OUTPUTDATAPanel, 'numeric');
            app.BaseROutput.Editable = 'off';
            app.DistPrivotOutput = uilabel(app.OUTPUTDATAPanel);
            app.DistPrivotOutput.Visible = 'off';
            app.DistPrivotOutput.Text = 'Dist. Cam''s Pivot vs Follower''s Pivot ';
            app.DistPiOutput = uieditfield(app.OUTPUTDATAPanel, 'numeric');
            app.DistPiOutput.Editable = 'off';
            app.DistPiOutput.Visible = 'off';
            app.FolArmLenLabel = uilabel(app.OUTPUTDATAPanel);
            app.FolArmLenLabel.Visible = 'off';
            app.FolArmLenLabel.Text = 'Follower Arm Length';
            app.FolArmLenOutput = uieditfield(app.OUTPUTDATAPanel, 'numeric');
            app.FolArmLenOutput.Editable = 'off';
            app.FolArmLenOutput.Visible = 'off';
            app.UIAxes_CamPro = uiaxes(app.OUTPUTDATAPanel);
            title(app.UIAxes_CamPro, 'CAM PROFILE')
            xlabel(app.UIAxes_CamPro, 'x (mm)')
            ylabel(app.UIAxes_CamPro, 'y (mm)')
            app.info_camsize = uibutton(app.OUTPUTDATAPanel, 'push');
            app.info_camsize.ButtonPushedFcn = createCallbackFcn(app, @info_camsizePushed, true);
            app.info_camsize.Text = 'More Information';
            app.Author = uilabel(app.OUTPUTDATAPanel);
            app.Author.Text = {'Author: Pham Le Khai'; 'Instructors: Pham Huy Hoang'; 'Ho Chi Minh City University of Technology'};
            % Show the figure after all components are created
            app.CamDesignerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cam_design

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CamDesignerUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CamDesignerUIFigure)
        end
    end
end