classdef app_bspl < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BSplineFunctionUIFigure  matlab.ui.Figure
        UITable                  matlab.ui.control.Table
        CamshaftAnglebetaLabel   matlab.ui.control.Label
        BetaEditField            matlab.ui.control.NumericEditField
        DisplacementsLabel       matlab.ui.control.Label
        sEditField               matlab.ui.control.NumericEditField
        INSERTButton             matlab.ui.control.Button
        UNDOButton               matlab.ui.control.Button
        DONEButton               matlab.ui.control.Button
        VelocityvLabel           matlab.ui.control.Label
        vEditField               matlab.ui.control.EditField
        AccelerationaLabel       matlab.ui.control.Label
        aEditField               matlab.ui.control.EditField
        OrderofSplineLabel       matlab.ui.control.Label
        nEditField               matlab.ui.control.NumericEditField
        JerkjLabel               matlab.ui.control.Label
        jEditField               matlab.ui.control.EditField
    end


    properties (Access = private)
        table = [] % Description
        s = []
        v = []
        a = []
        j = []
        CallingApp % Description
        bf
        sf
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, beta_first, s_first)
            app.CallingApp = mainapp;
            app.bf = beta_first;
            app.sf = s_first;
            if isnan(app.sf)
                app.BetaEditField.Value = app.bf;
                app.sEditField.Value = app.sf;
            end
        end

        % Button pushed function: INSERTButton
        function INSERTButtonPushed(app, event)
            beta = app.BetaEditField.Value;
            s_local = app.sEditField.Value;
            if isempty(app.vEditField.Value)
                v_local = nan;
            else
                v_local = double(str2sym(app.vEditField.Value));
            end
            if isempty(app.aEditField.Value)
                a_local = nan;
            else
                a_local = double(str2sym(app.aEditField.Value));
            end
            if isempty(app.jEditField.Value)
                j_local = nan;
            else
                j_local = double(str2sym(app.jEditField.Value));
            end
            app.table = [app.table; beta s_local v_local a_local j_local];
            app.UITable.Data = app.table;
            app.aEditField.Value = '';
            app.jEditField.Value = '';
            app.UNDOButton.Enable = 'on';
            app.DONEButton.Enable = 'on';                        
        end

        % Button pushed function: UNDOButton
        function UNDOButtonPushed(app, event)
            if size(app.table,1)==1
                app.BetaEditField.Value = app.bf;
                app.sEditField.Value = app.sf;
                app.DONEButton.Enable='off';
                app.UNDOButton.Enable='off';
            end
            app.table(size(app.table,1),:) = [];
            app.UITable.Data = app.table;
        end

        % Button pushed function: DONEButton
        function DONEButtonPushed(app, event)
            tranferdata(app.CallingApp,app.table,app.nEditField.Value)
            delete(app)
        end

        % Close request function: BSplineFunctionUIFigure
        function BSplineFunctionUIFigureCloseRequest(app, event)
            change2dwell(app.CallingApp)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BSplineFunctionUIFigure = uifigure('Visible', 'off');
            app.BSplineFunctionUIFigure.Name = 'B-Spline Function';
            app.BSplineFunctionUIFigure.CloseRequestFcn = createCallbackFcn(app, @BSplineFunctionUIFigureCloseRequest, true);
            app.UITable = uitable(app.BSplineFunctionUIFigure);
            app.UITable.ColumnName = {'beta'; 's'; 'v'; 'a'; 'j'};
            app.UITable.RowName = {};
            app.CamshaftAnglebetaLabel = uilabel(app.BSplineFunctionUIFigure);
            app.CamshaftAnglebetaLabel.Text = 'Camshaft Angle, beta';
            app.BetaEditField = uieditfield(app.BSplineFunctionUIFigure, 'numeric');
            app.DisplacementsLabel = uilabel(app.BSplineFunctionUIFigure);
            app.DisplacementsLabel.Text = 'Displacement, s';
            app.sEditField = uieditfield(app.BSplineFunctionUIFigure, 'numeric');
            app.INSERTButton = uibutton(app.BSplineFunctionUIFigure, 'push');
            app.INSERTButton.ButtonPushedFcn = createCallbackFcn(app, @INSERTButtonPushed, true);
            app.INSERTButton.Text = 'INSERT';
            app.UNDOButton = uibutton(app.BSplineFunctionUIFigure, 'push');
            app.UNDOButton.ButtonPushedFcn = createCallbackFcn(app, @UNDOButtonPushed, true);
            app.UNDOButton.Enable = 'off';
            app.UNDOButton.Text = 'UNDO';
            app.DONEButton = uibutton(app.BSplineFunctionUIFigure, 'push');
            app.DONEButton.ButtonPushedFcn = createCallbackFcn(app, @DONEButtonPushed, true);
            app.DONEButton.Enable = 'off';
            app.DONEButton.Text = 'DONE';
            app.VelocityvLabel = uilabel(app.BSplineFunctionUIFigure);
            app.VelocityvLabel.Text = 'Velocity, v';
            app.vEditField = uieditfield(app.BSplineFunctionUIFigure, 'text');
            app.AccelerationaLabel = uilabel(app.BSplineFunctionUIFigure);
            app.AccelerationaLabel.Text = 'Acceleration, a';
            app.aEditField = uieditfield(app.BSplineFunctionUIFigure, 'text');
            app.OrderofSplineLabel = uilabel(app.BSplineFunctionUIFigure);
            app.OrderofSplineLabel.Text = 'Order of Spline';
            app.nEditField = uieditfield(app.BSplineFunctionUIFigure, 'numeric');
            app.nEditField.Limits = [3 20];
            app.nEditField.Value = 6;
            app.JerkjLabel = uilabel(app.BSplineFunctionUIFigure);
            app.JerkjLabel.Text = 'Jerk, j';
            app.jEditField = uieditfield(app.BSplineFunctionUIFigure, 'text');
            % Show the figure after all components are created
            app.BSplineFunctionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_bspl(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BSplineFunctionUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BSplineFunctionUIFigure)
        end
    end
end