classdef app_camsize < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CamSizeUIFigure  matlab.ui.Figure
        UIAxes_rho       matlab.ui.control.UIAxes
        UIAxes_phi       matlab.ui.control.UIAxes
        phi_label        matlab.ui.control.Label
        phi_value        matlab.ui.control.NumericEditField
        rho_label        matlab.ui.control.Label
        rho_p            matlab.ui.control.NumericEditField
    end


    properties (Access = private)
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, x, phi, rho, type, unit)
            % Xuat do thi & min |rho_p|
            plot(app.UIAxes_rho,x,rho,'b');
            if unit, xlabel(app.UIAxes_rho, 'x (deg)'); % unit == 1, deg, rad
            else, xlabel(app.UIAxes_rho, 'x (rad)'); end
            app.rho_p.Value = min(abs(rho));
            % Xuat do thi & max |phi|
            if strcmp(type,'Cam with Translating Flat-faced Follower') ||...
                    strcmp(type,'Cam with Ocsilating Flat-faced Follower')
                % An text va do thi
                app.UIAxes_phi.Visible = 'off';
                app.phi_label.Visible = 'off';
                app.phi_value.Visible = 'off';
                % Dieu chinh kich thuoc
                app.CamSizeUIFigure.Position = [200,527,580,300];
            else
                % Doi don vi
                if unit 
                    phi = phi/pi*180;
                    xlabel(app.UIAxes_phi, 'x (deg)');
                    ylabel(app.UIAxes_phi, '\phi (deg)');
                else
                    xlabel(app.UIAxes_phi, 'x (rad)');
                    ylabel(app.UIAxes_phi, '\phi (rad)');
                end
                % Hien text
                app.phi_label.Visible = 'on';
                app.phi_value.Visible='on';
                app.phi_value.Value=max(abs(phi));
                % Hien do thi
                app.UIAxes_phi.Visible='on';
                plot(app.UIAxes_phi,x,phi,'g');
                % Dieu chinh kich thuoc
                app.CamSizeUIFigure.Position = [200,227,580,600];
            end      
        end

        % Close request function: CamSizeUIFigure
        function CamSizeUIFigureCloseRequest(app, event)
            cla(app.UIAxes_rho)
            cla(app.UIAxes_phi)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CamSizeUIFigure = uifigure('Visible', 'off');
            app.CamSizeUIFigure.Name = 'Cam Size';
            app.CamSizeUIFigure.CloseRequestFcn = createCallbackFcn(app, @CamSizeUIFigureCloseRequest, true);
            app.UIAxes_rho = uiaxes(app.CamSizeUIFigure);
            title(app.UIAxes_rho, 'Radius of Curvature')
            xlabel(app.UIAxes_rho, 'X')
            ylabel(app.UIAxes_rho, '\rho (mm)')
            app.UIAxes_phi = uiaxes(app.CamSizeUIFigure);
            title(app.UIAxes_phi, 'Pressure Angle')
            xlabel(app.UIAxes_phi, 'X')
            ylabel(app.UIAxes_phi, 'Y')
            app.phi_label = uilabel(app.CamSizeUIFigure);
            app.phi_label.Text = 'Magnitude of Max. Pressure Angle ';
            app.phi_value = uieditfield(app.CamSizeUIFigure, 'numeric');
            app.rho_label = uilabel(app.CamSizeUIFigure);
            app.rho_label.Text = 'Magnitude of Min. Radius of Curvature ';
            app.rho_p = uieditfield(app.CamSizeUIFigure, 'numeric');
            % Show the figure after all components are created
            app.CamSizeUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_camsize(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CamSizeUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CamSizeUIFigure)
        end
    end
end