classdef app_motion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SVAJDiagramsUIFigure           matlab.ui.Figure
        s_plot                         matlab.ui.control.UIAxes
        v_plot                         matlab.ui.control.UIAxes
        j_plot                         matlab.ui.control.UIAxes
        a_plot                         matlab.ui.control.UIAxes
        MaxDisplacementEditFieldLabel  matlab.ui.control.Label
        MaxDisplacementEditField       matlab.ui.control.NumericEditField
        MinDisplacementEditFieldLabel  matlab.ui.control.Label
        MinDisplacementEditField       matlab.ui.control.NumericEditField
        MaxVelocityEditFieldLabel      matlab.ui.control.Label
        MaxVelocityEditField           matlab.ui.control.NumericEditField
        MinVelocityEditFieldLabel      matlab.ui.control.Label
        MinVelocityEditField           matlab.ui.control.NumericEditField
        MaxAccelerationEditFieldLabel  matlab.ui.control.Label
        MaxAccelerationEditField       matlab.ui.control.NumericEditField
        MinAccelerationEditFieldLabel  matlab.ui.control.Label
        MinAccelerationEditField       matlab.ui.control.NumericEditField
        MaxJerkEditFieldLabel          matlab.ui.control.Label
        MaxJerkEditField               matlab.ui.control.NumericEditField
        MinJerkEditFieldLabel          matlab.ui.control.Label
        MinJerkEditField               matlab.ui.control.NumericEditField
    end


    properties (Access = private)
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, x, s, v, a, j, omega, unit, type)
            if unit % unit == 1, deg, rad
                omega = omega*360/60;
                xlabel(app.s_plot, 'x (deg)');
                xlabel(app.v_plot, 'x (deg)');
                xlabel(app.a_plot, 'x (deg)');
                xlabel(app.j_plot, 'x (deg)');
                if strcmp(type,'Cam with Ocsilating Roller Follower') ||...
                           strcmp(type,'Cam with Ocsilating Flat-faced Follower')
                    ylabel(app.s_plot, 's (deg)');
                    ylabel(app.v_plot, 'v (deg/s)');
                    ylabel(app.a_plot, 'a (deg/s^{2})');
                    ylabel(app.j_plot, 'j (deg/s^{3})');
                else
                    ylabel(app.s_plot, 's (mm)');
                    ylabel(app.v_plot, 'v (mm/s)');
                    ylabel(app.a_plot, 'a (mm/s^{2})');
                    ylabel(app.j_plot, 'j (mm/s^{3})');
                end
            else
                omega = omega*2*pi/60;
                xlabel(app.s_plot, 'x (rad)');
                xlabel(app.v_plot, 'x (rad)');
                xlabel(app.a_plot, 'x (rad)');
                xlabel(app.j_plot, 'x (rad)');
                if strcmp(type,'Cam with Ocsilating Roller Follower') ||...
                           strcmp(type,'Cam with Ocsilating Flat-faced Follower')
                    ylabel(app.s_plot, 's (rad)');
                    ylabel(app.s_plot, 's (rad/s)');
                    ylabel(app.s_plot, 's (rad/s^{2})');
                    ylabel(app.s_plot, 's (rad/s^{3})');
                else
                    ylabel(app.s_plot, 's (mm)');
                    ylabel(app.v_plot, 'v (mm/s)');
                    ylabel(app.a_plot, 'a (mm/s^{2})');
                    ylabel(app.j_plot, 'j (mm/s^{3})');
                end
            end
            
            plot(app.s_plot, x, s);
            plot(app.v_plot, x, v*omega);
            plot(app.a_plot, x, a*omega^2);
            plot(app.j_plot, x, j*omega^3);
            app.MaxDisplacementEditField.Value = max(s);
            app.MinDisplacementEditField.Value = min(s);
            app.MaxVelocityEditField.Value = max(v*omega);
            app.MinVelocityEditField.Value = min(v*omega);
            app.MaxAccelerationEditField.Value = max(a*omega^2);
            app.MinAccelerationEditField.Value = min(a*omega^2);
            app.MaxJerkEditField.Value = max(j*omega^3);
            app.MinJerkEditField.Value = min(j*omega^3);
        end

        % Close request function: SVAJDiagramsUIFigure
        function SVAJDiagramsUIFigureCloseRequest(app, event)
            cla(app.s_plot)
            cla(app.v_plot)
            cla(app.a_plot)
            cla(app.j_plot)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SVAJDiagramsUIFigure = uifigure('Visible', 'off');
            app.SVAJDiagramsUIFigure.Name = 'S V A J Diagrams';
            app.SVAJDiagramsUIFigure.CloseRequestFcn = createCallbackFcn(app, @SVAJDiagramsUIFigureCloseRequest, true);
            app.s_plot = uiaxes(app.SVAJDiagramsUIFigure);
            title(app.s_plot, 'Displacement')
            xlabel(app.s_plot, '')
            ylabel(app.s_plot, '')
            app.v_plot = uiaxes(app.SVAJDiagramsUIFigure);
            title(app.v_plot, 'Velocity')
            xlabel(app.v_plot, '')
            ylabel(app.v_plot, '')
            app.j_plot = uiaxes(app.SVAJDiagramsUIFigure);
            title(app.j_plot, 'Jerk')
            xlabel(app.j_plot, '')
            ylabel(app.j_plot, '')
            app.a_plot = uiaxes(app.SVAJDiagramsUIFigure);
            title(app.a_plot, 'Acceleration')
            xlabel(app.a_plot, '')
            ylabel(app.a_plot, '')
            app.MaxDisplacementEditFieldLabel = uilabel(app.SVAJDiagramsUIFigure);
            app.MaxDisplacementEditFieldLabel.Text = 'Max. Displacement';
            app.MaxDisplacementEditField = uieditfield(app.SVAJDiagramsUIFigure, 'numeric');
            app.MinDisplacementEditFieldLabel = uilabel(app.SVAJDiagramsUIFigure);
            app.MinDisplacementEditFieldLabel.Text = 'Min. Displacement';
            app.MinDisplacementEditField = uieditfield(app.SVAJDiagramsUIFigure, 'numeric');
            app.MaxVelocityEditFieldLabel = uilabel(app.SVAJDiagramsUIFigure);
            app.MaxVelocityEditFieldLabel.Text = 'Max. Velocity';
            app.MaxVelocityEditField = uieditfield(app.SVAJDiagramsUIFigure, 'numeric');
            app.MinVelocityEditFieldLabel = uilabel(app.SVAJDiagramsUIFigure);
            app.MinVelocityEditFieldLabel.Text = 'Min. Velocity';
            app.MinVelocityEditField = uieditfield(app.SVAJDiagramsUIFigure, 'numeric');
            app.MaxAccelerationEditFieldLabel = uilabel(app.SVAJDiagramsUIFigure);
            app.MaxAccelerationEditFieldLabel.Text = 'Max. Acceleration';
            app.MaxAccelerationEditField = uieditfield(app.SVAJDiagramsUIFigure, 'numeric');
            app.MinAccelerationEditFieldLabel = uilabel(app.SVAJDiagramsUIFigure);
            app.MinAccelerationEditFieldLabel.Text = 'Min. Acceleration';
            app.MinAccelerationEditField = uieditfield(app.SVAJDiagramsUIFigure, 'numeric');
            app.MaxJerkEditFieldLabel = uilabel(app.SVAJDiagramsUIFigure);
            app.MaxJerkEditFieldLabel.Text = 'Max. Jerk';
            app.MaxJerkEditField = uieditfield(app.SVAJDiagramsUIFigure, 'numeric');
            app.MinJerkEditFieldLabel = uilabel(app.SVAJDiagramsUIFigure);
            app.MinJerkEditFieldLabel.Text = 'Min. Jerk';
            app.MinJerkEditField = uieditfield(app.SVAJDiagramsUIFigure, 'numeric');
            % Show the figure after all components are created
            app.SVAJDiagramsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_motion(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SVAJDiagramsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SVAJDiagramsUIFigure)
        end
    end
end